classdef Microstructure_generation_additives < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Microstructure_additive_phase_generation  matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        LoadvolumeTab                  matlab.ui.container.Tab
        Load_instructions              matlab.ui.control.Label
        Load_title                     matlab.ui.control.Label
        Load_LoadvolumeButton          matlab.ui.control.Button
        Load_VolumeloaedLabel          matlab.ui.control.Label
        LoadFilenameLabel              matlab.ui.control.Label
        Load_filename_save             matlab.ui.control.EditField
        Load_savefolderButton          matlab.ui.control.Button
        Load_savefolderLabel           matlab.ui.control.Label
        Load_ExistingphasesUITable     matlab.ui.control.Table
        Load_ExistingphasesLabel       matlab.ui.control.Label
        BetweenneighboursparticlesTab  matlab.ui.container.Tab
        Bridge_instructions            matlab.ui.control.Label
        Bridge_title                   matlab.ui.control.Label
        Bridge_NewphaseparameterLabel  matlab.ui.control.Label
        VolumefractiondenseLabel       matlab.ui.control.Label
        Bridge_volumefraction_dense    matlab.ui.control.NumericEditField
        NanoporosityLabel              matlab.ui.control.Label
        Bridge_nanoporosity            matlab.ui.control.NumericEditField
        TargetvolumefractionLabel      matlab.ui.control.Label
        Bridge_targetvolumefraction    matlab.ui.control.NumericEditField
        Bridge_parameters_UITable      matlab.ui.control.Table
        Bridge_generation              matlab.ui.control.Button
        ProgressionGaugeLabel          matlab.ui.control.Label
        ProgressionGauge               matlab.ui.control.LinearGauge
        TextArea                       matlab.ui.control.TextArea
        Bridge_save                    matlab.ui.control.Button
        AchievedEditFieldLabel         matlab.ui.control.Label
        Bridged_AchievedEditField      matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        Microstructure=[]; % Microstructure initialized empty
        Microstructure_initial=[]; % Microstructure initialized empty
        History_operations=[]; History_parameters=[]; History_elapsedtime=[]; % Log
        Savefolder=[]; % save folder
        filesave=[]; % file savename
    end
    
    methods (Access = private)
        
        function [] = Turn_on_off(app,status_GUI,tab)
            child_handles = allchild(tab); % Get all children
            [n_children,~]=size(child_handles);
            for k=1:1:n_children
                child_handles(k).Visible=status_GUI;
                if isprop(child_handles(k),'Enable')
                    child_handles(k).Enable=status_GUI;
                end
            end
            
            if strcmp(tab.Title,'Between neighbours particles')
                app.Bridge_generation.Enable='off';
                app.Bridge_save.Enable='off';
            end
        end
        
        function [] = Check_can_generate(app,tab)
            idx = find( cell2mat(app.Load_ExistingphasesUITable.Data(:,3))==1 );
            
            if strcmp(tab.Title,'Between neighbours particles')
                target_volume_fraction = app.Bridge_targetvolumefraction.Value;
                if ~isempty(idx) && target_volume_fraction<=cell2mat(app.Load_ExistingphasesUITable.Data(idx,2))
                    app.Bridge_generation.Enable = 'on';
                else
                    app.Bridge_generation.Enable = 'off';
                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.Turn_on_off('off',app.RandomspatialdistributionTab)
            %app.Turn_on_off('off',app.ThinsurfacelayerTab)
            app.Turn_on_off('off',app.BetweenneighboursparticlesTab)
        end

        % Button pushed function: Load_LoadvolumeButton
        function Load_LoadvolumeButtonPushed(app, event)
            % Open dialog box to choose file path
            [FileName,PathName,~] = uigetfile({'*.tif','Tif image (*.tif)'},'Select a .tif file');
            if FileName==0
                % User clicked cancel button or closed the dialog box
                app.Load_VolumeloaedLabel.Text ='No volume loaded. Please load a volume.';
                app.Load_savefolderLabel.Text = 'No save folder selected. Please select a folder.';
                app.Load_filename_save.Value = 'none';
                app.Microstructure=[]; % Reset Microstructure
                app.Microstructure_initial=[]; % Reset Microstructure
                app.History_operations=[]; app.History_parameters=[]; app.History_elapsedtime=[]; % Reset log
                app.Savefolder=[];
                app.filesave=[];
                app.Turn_on_off('off',app.BetweenneighboursparticlesTab)
                app.Load_ExistingphasesUITable.Data = [];
            else
                [Microstructure_local,outcome] = function_load_tif([PathName FileName]);
                if outcome % Success to import

                    % Microstructure_local=Microstructure_local(:,:,1:2); % Debug
                    % Microstructure_local(:,:,2)=Microstructure_local(:,:,1);
                    
                    % Update global variables
                    app.Microstructure=Microstructure_local; % Update Microstructure
                    app.Microstructure_initial = app.Microstructure;
                    
                    %% Load tab
                    % Update save folder
                    app.Savefolder = PathName;
                    app.Load_VolumeloaedLabel.Text = [PathName FileName];
                    app.Load_savefolderLabel.Text = app.Savefolder;
                    [~,tmp,~] = fileparts(FileName); % Remove .tif
                    app.filesave= [tmp '_bridge.tif'];
                    app.Load_filename_save.Value = app.filesave;
                    app.Turn_on_off('on',app.BetweenneighboursparticlesTab)
                    % Volume fraction and background
                    phases = unique(Microstructure_local);
                    number_phase = length(phases);
                    tabledata=cell(number_phase,3);
                    for current_phase=1:1:number_phase
                        tabledata(current_phase,1)={num2str(phases(current_phase))};
                        tabledata(current_phase,2)={sum(sum(sum( Microstructure_local==phases(current_phase)  ))) / numel(Microstructure_local)};
                        if phases(current_phase)==0
                            tabledata(current_phase,3)={true}; % By default, background is phase 0
                        else
                            tabledata(current_phase,3)={false};
                        end
                        
                    end
                    app.Load_ExistingphasesUITable.Data = tabledata;
                    
                    %% Between neighbours particles
                    tabledata=cell(4,3);
                    tabledata(1,:)={'Additive Id','[0,+Inf], Integer',max(phases)+1};
                    tabledata(2,:)={'Randomize cluster selection','[0,1]',0};
                    tabledata(3,:)={'Minimum distance from boundary in voxel length','[0,+Inf]',1};
                    tabledata(4,:)={'Erosion depth in voxel length','[0,+Inf] ',1};
                    app.Bridge_parameters_UITable.Data = tabledata;
                    
                    app.Check_can_generate(app.BetweenneighboursparticlesTab)
                    
                else
                    app.Load_VolumeloaedLabel.Text ='Error: volume cannot be loaded! File is not a .tif file';
                    app.Load_savefolderLabel.Text = 'No save folder selected. Please select a folder.';
                    app.Load_filename_save.Value = 'none';
                    app.Microstructure=[]; % Reset Microstructure
                    app.Microstructure_initial=[]; % Reset Microstructure
                    app.History_operations=[]; app.History_parameters=[]; app.History_elapsedtime=[]; % Reset log
                    app.Savefolder=[];
                    app.filesave=[];
                    app.Turn_on_off('off',app.BetweenneighboursparticlesTab)
                    app.Load_ExistingphasesUITable.Data = [];
                end
            end
            
            
        end

        % Cell edit callback: Load_ExistingphasesUITable
        function Load_ExistingphasesUITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(2)==3
                tabledata = app.Load_ExistingphasesUITable.Data;
                tabledata(:,3) = {false};
                if newData==1
                    tabledata(indices(1),3)={true};
                end
                app.Load_ExistingphasesUITable.Data = tabledata;
            end
            app.Check_can_generate(app.BetweenneighboursparticlesTab)
        end

        % Value changed function: Bridge_nanoporosity, 
        % Bridge_volumefraction_dense
        function Bridge_volumefraction_denseValueChanged(app, event)
            volumefraction_dense = app.Bridge_volumefraction_dense.Value;
            nanoporosity = app.Bridge_nanoporosity.Value;
            target_volume_fraction = volumefraction_dense * 1/(1-nanoporosity);
            idx = find( cell2mat(app.Load_ExistingphasesUITable.Data(:,3))==1 );
            if ~isempty(idx)
                max_vf = cell2mat(app.Load_ExistingphasesUITable.Data(idx,2));
            else
                max_vf = 9e9;
            end
            app.Bridge_targetvolumefraction.Value = min([max_vf, target_volume_fraction]);
            app.Check_can_generate(app.BetweenneighboursparticlesTab)
        end

        % Button pushed function: Bridge_generation
        function Bridge_generationButtonPushed(app, event)
            
            % Prepare parameters
            idx = find( cell2mat(app.Load_ExistingphasesUITable.Data(:,3))==1 );
            app.Microstructure = app.Microstructure_initial; % Reset
            background_id = str2num(cell2mat(app.Load_ExistingphasesUITable.Data(idx,1)));
            target_volume_fraction = app.Bridge_targetvolumefraction.Value;
            additive_id = double(cell2mat(app.Bridge_parameters_UITable.Data(1,3)));
            randomize_level = cell2mat(app.Bridge_parameters_UITable.Data(2,3));
            minimum_dist = cell2mat(app.Bridge_parameters_UITable.Data(3,3));
            erosiondilatation_depth = cell2mat(app.Bridge_parameters_UITable.Data(4,3));
            
            % You could use it as a function as
            %function [app.Microstructure] = generate_additive(app.Microstructure,background_id,additive_id,target_volume_fraction,minimum_dist,randomize_level,erosiondilatation_depth)
            
            time_cpu_start = cputime; % CPU start
            tic; % Stopwatch start
            
            app.ProgressionGauge.Value = 0; pause(0.001); % Update progression bar
            app.TextArea.Value = {'Calculating','distance to boundary...'}; pause(0.01);
            
            binary_phase=zeros(size(app.Microstructure)); % Initialization
            binary_phase(app.Microstructure == background_id) = 1;
            [distance_bnd2bnd] = Function_particle_size_CPSD_Algorithm(binary_phase); % Calculate distance from boundary to boundary
            
%            % Visualization step-by-step
%             figure
%             axis equal tight
%             imagesc(distance_bnd2bnd(:,:,2))  
%             set(gca,'YDir','normal')
%             custommap = jet; custommap(1,:)=[0 0 0];
%             colormap(custommap)
            
            distance_bnd2bnd(distance_bnd2bnd==0)=9e9; % Remove 0 (background) from the potential additive location
            distance_bnd2bnd(distance_bnd2bnd<=minimum_dist*sqrt(3))=9e9; % Remove small features from the potential additive location
            
            app.TextArea.Value = {'Generating','additive phase...'}; pause(0.01);
                        
            ntot = numel(app.Microstructure);
            current_additive_volumefraction = 0; % Initialize
            d=0; % Initialize
            while current_additive_volumefraction < target_volume_fraction
                d=d+1; % Increment distance
                if d>minimum_dist*sqrt(3)
                    potential_additives = distance_bnd2bnd<=d; % Select all voxels that could be assigned to additive
                    n = sum(sum(sum(potential_additives))); % How many of them ?
                    if n>0 % At least one
                        if randomize_level==0
                            if n/ntot <= target_volume_fraction-current_additive_volumefraction % All potential voxel are not enough to match target volume fraction
                                app.Microstructure(potential_additives)=additive_id;
                            else % Randomize among possible location is mandatory not to overshoot the target volume fraction
                                [L,ncluster] = bwlabeln(potential_additives,18); % Identify cluster among these potential locations
                                cluster_possibility=1:1:ncluster;
                                while current_additive_volumefraction <= target_volume_fraction
                                    cluster_choice=randi(length(cluster_possibility)); % Random selection of cluster
                                    app.Microstructure(L==cluster_possibility(cluster_choice))=additive_id;
                                    current_additive_volumefraction = sum(sum(sum(app.Microstructure==additive_id)))/ntot; % Update current volume fraction
                                    cluster_possibility(cluster_choice)=[]; % Avoid re-assignment
                                end
                            end
                        else % Use a portion of them, per cluster randomly chosen
                            [L,ncluster] = bwlabeln(potential_additives,18); % Identify cluster among these potential locations
                            cluster_possibility=1:1:ncluster;
                            volume_increment=0; % initalize
                            while volume_increment <= (1-randomize_level)*n
                                cluster_choice=randi(length(cluster_possibility)); % Random selection of cluster
                                loc = L==cluster_possibility(cluster_choice);
                                app.Microstructure(loc)=additive_id;
                                volume_increment = volume_increment + sum(sum(sum( loc )));
                                cluster_possibility(cluster_choice)=[]; % Avoid re-assignment
                            end
                        end
                    end
                end
                distance_bnd2bnd(app.Microstructure==additive_id)=9e9; % Remove already assigned additive from the potential additive new location
                current_additive_volumefraction = sum(sum(sum(app.Microstructure==additive_id)))/ntot; % Update current volume fraction
                
                app.ProgressionGauge.Value = 100*current_additive_volumefraction/target_volume_fraction; pause(0.001); % Update progression bar
                
%                %Visualization step-by-step
%                 figure
%                 axis equal tight
%                 imagesc(app.Microstructure(:,:,2))
%                 set(gca,'YDir','normal')
%                 custommap = copper; custommap(1,:)=[0 0 0];
%                 colormap(custommap)
                
                
            end
            app.ProgressionGauge.Value = 100; pause(0.001); % Update progression bar
                        
            if erosiondilatation_depth>0 % Optional step
                app.TextArea.Value = {'Erosion of additive phase...'}; pause(0.01);
                binary_phase=zeros(size(app.Microstructure)); % Initialization
                binary_phase(app.Microstructure == additive_id) = 1;
                [distance_bnd2bnd] = Function_particle_size_CPSD_Algorithm(binary_phase); % Calculate distance from boundary to boundary
                distance_bnd2bnd(distance_bnd2bnd==0)=9e9; % Remove 0 (background) from the potential additive location
                app.Microstructure(distance_bnd2bnd<(erosiondilatation_depth*sqrt(3)+0.0001))=background_id;                
            end
             
% %             Visualization step-by-step
%             figure
%             axis equal tight
%             imagesc(app.Microstructure(:,:,2))
%             set(gca,'YDir','normal')
%             custommap = copper; custommap(1,:)=[0 0 0];
%             colormap(custommap)
            
            if minimum_dist>0
                app.TextArea.Value = {'Correcting minimum','distance...'}; pause(0.01);
                binary_phase=zeros(size(app.Microstructure)); % Initialization
                binary_phase(app.Microstructure == background_id) = 1;
                [distance_bnd2bnd] = Function_particle_size_CPSD_Algorithm(binary_phase); % Calculate distance from boundary to boundary
                distance_bnd2bnd(distance_bnd2bnd==0)=9e9; % Remove 0 (background) from the potential additive location
                app.Microstructure(distance_bnd2bnd<minimum_dist*sqrt(3)+0.0001)=additive_id;
            end
            
% %             Visualization step-by-step
%             figure
%             axis equal tight
%             imagesc(app.Microstructure(:,:,2))
%             set(gca,'YDir','normal')
%             custommap = copper; custommap(1,:)=[0 0 0];
%             colormap(custommap)            
    

            delta = round(sum(sum(sum( app.Microstructure==additive_id  ))) - numel(app.Microstructure)*target_volume_fraction)
            erosion_cluster = true;
            while delta>0
                %erosion_cluster
                %delta
                app.TextArea.Value = {'Resorbing volume fraction','difference...'}; pause(0.01);
                binary_phase=zeros(size(app.Microstructure)); % Create binary matrix
                if erosion_cluster==false
                    % Random voxel erosion
                    binary_phase(app.Microstructure==background_id) = 1;
                    distance_map = bwdist(binary_phase,'chessboard'); % Distance map
                    distance_map(app.Microstructure ~= additive_id)=2; % Consider only additive
                    idx = find(distance_map<2);
                    if length(idx)<=delta
                        app.Microstructure(idx)=background_id;
                    else
                        tmp = randi(length(idx),delta,1);
                        app.Microstructure(idx(tmp))=background_id;
                    end
                else
                    % Random cluster erosion
                    binary_phase(app.Microstructure==additive_id) = 1;
                    [L,~] = bwlabeln(binary_phase,18);
                    % Determine cluster size
                    [C,~,ic] = unique(L);
                    a_counts = accumarray(ic,1);
                    size_cluster = [C, a_counts];
                    size_cluster = sortrows(size_cluster,2); % Sort in ascending order
                    while size_cluster(1,2)<delta && delta>0 
                        app.Microstructure(L==size_cluster(1,1))=background_id; % Smallest cluster is removed
                        delta = round(sum(sum(sum( app.Microstructure==additive_id  ))) - numel(app.Microstructure)*target_volume_fraction);
                        size_cluster(1,:)=[]; % Smallest cluster is removed from the list 
                    end
                    erosion_cluster = false; % Switch to voxel erosion
                end
                delta = round(sum(sum(sum( app.Microstructure==additive_id  ))) - numel(app.Microstructure)*target_volume_fraction);
            end
           
              
% %             Visualization step-by-step
%             figure
%             axis equal tight
%             imagesc(app.Microstructure(:,:,2))
%             set(gca,'YDir','normal')
%             custommap = copper; custommap(1,:)=[0 0 0];
%             colormap(custommap)               
            
            % CPU and stopwatch time - end
            time_cpu_elapsed = cputime-time_cpu_start; % CPU elapsed time
            time_stopwatch_elapsed = toc; % Stopwatch elapsed time
            
            app.TextArea.Value = {[num2str(ntot,'%i'),' voxels (domain)'],['Stopwatch time:',num2str(time_stopwatch_elapsed,'%1.1f'), 's'],['CPU time:',num2str(time_cpu_elapsed,'%1.1f'), 's']}; pause(0.01);
            app.Bridged_AchievedEditField.Value = sum(sum(sum( app.Microstructure==additive_id  ))) / numel(app.Microstructure);
            
            % Done
            app.Bridge_save.Enable='on';
        end

        % Button pushed function: Bridge_save
        function Bridge_saveButtonPushed(app, event)
            function_save_tif(app.Microstructure, [app.Savefolder app.Load_filename_save.Value]);
            %for current_phase=0:1:2
            %    vf=sum(sum(sum( app.Microstructure==current_phase  ))) / numel(app.Microstructure)
            %end
        end

        % Button pushed function: Load_savefolderButton
        function Load_savefolderButtonPushed(app, event)
            if isempty(app.Savefolder)
                selpath = uigetdir(pwd,'Select save folder');
            else
                selpath = uigetdir(app.Savefolder,'Select save folder');
            end
            if selpath~=0
                if ispc
                    selpath=[selpath '\'];
                else
                    selpath=[selpath '/'];
                end
                app.Savefolder=selpath;
                app.Load_savefolderLabel.Text = app.Savefolder;
            end
        end

        % Cell edit callback: Bridge_parameters_UITable
        function Bridge_parameters_UITableCellEdit(app, event)
            % Check parameters
            tabledata = app.Bridge_parameters_UITable.Data;
            v=cell2mat(tabledata(1,3));
            if isnan(v) || ismember(double(v),str2num(cell2mat(app.Load_ExistingphasesUITable.Data(:,1)))) || v<0 || v~=round(v)
                tabledata(1,3) = {max(str2num(cell2mat(app.Load_ExistingphasesUITable.Data(:,1))))+1};
            end
            v=cell2mat(tabledata(2,3));
            if isnan(v) || v<0 || v>1
                tabledata(2,3) = {0};
            end            
            v=cell2mat(tabledata(3,3));
            if isnan(v) || v<0
                tabledata(3,3) = {1};
            end                  
            v=cell2mat(tabledata(4,3));
            if isnan(v) || v<0 || v~=round(v)
                tabledata(4,3) = {1};
            end           
            app.Bridge_parameters_UITable.Data = tabledata;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Microstructure_additive_phase_generation = uifigure('Visible', 'off');
            app.Microstructure_additive_phase_generation.Name = 'Additive phase generation';
            app.TabGroup = uitabgroup(app.Microstructure_additive_phase_generation);
            app.LoadvolumeTab = uitab(app.TabGroup);
            app.LoadvolumeTab.Title = 'Load volume';
            app.Load_instructions = uilabel(app.LoadvolumeTab);
            app.Load_instructions.Text = {'Instructions: load a volume, select a save folder and write filename. Select background, then move to the tab that corresponds to the type'; 'of additive you want to generate.'};
            app.Load_title = uilabel(app.LoadvolumeTab);
            app.Load_title.Text = 'Volume with pre-existing phases';
            app.Load_LoadvolumeButton = uibutton(app.LoadvolumeTab, 'push');
            app.Load_LoadvolumeButton.ButtonPushedFcn = createCallbackFcn(app, @Load_LoadvolumeButtonPushed, true);
            app.Load_LoadvolumeButton.Text = 'Load volume';
            app.Load_VolumeloaedLabel = uilabel(app.LoadvolumeTab);
            app.Load_VolumeloaedLabel.Text = 'No volume loaded. Please load a volume.';
            app.LoadFilenameLabel = uilabel(app.LoadvolumeTab);
            app.LoadFilenameLabel.Text = 'Save Filename';
            app.Load_filename_save = uieditfield(app.LoadvolumeTab, 'text');
            app.Load_filename_save.Value = 'none';
            app.Load_savefolderButton = uibutton(app.LoadvolumeTab, 'push');
            app.Load_savefolderButton.ButtonPushedFcn = createCallbackFcn(app, @Load_savefolderButtonPushed, true);
            app.Load_savefolderButton.Text = 'Save folder';
            app.Load_savefolderLabel = uilabel(app.LoadvolumeTab);
            app.Load_savefolderLabel.Text = 'No save folder selected. Please select a folder.';
            app.Load_ExistingphasesUITable = uitable(app.LoadvolumeTab);
            app.Load_ExistingphasesUITable.ColumnName = {'Id'; 'Volume fraction'; 'Background'};
            app.Load_ExistingphasesUITable.RowName = {};
            app.Load_ExistingphasesUITable.ColumnEditable = [false false true];
            app.Load_ExistingphasesUITable.CellEditCallback = createCallbackFcn(app, @Load_ExistingphasesUITableCellEdit, true);
            app.Load_ExistingphasesLabel = uilabel(app.LoadvolumeTab);
            app.Load_ExistingphasesLabel.Text = 'Existing phases. Select background where additive will be generated';
            app.BetweenneighboursparticlesTab = uitab(app.TabGroup);
            app.BetweenneighboursparticlesTab.Title = 'Between neighbours particles';
            app.Bridge_instructions = uilabel(app.BetweenneighboursparticlesTab);
            app.Bridge_instructions.Text = 'Instructions: Choose algorithms parameters and click generate. Once done, click on the save button to create a .tif file';
            app.Bridge_title = uilabel(app.BetweenneighboursparticlesTab);
            app.Bridge_title.Text = 'Additive phase will be generated so that they will connect neighbours particles, effectively forming bridges';
            app.Bridge_NewphaseparameterLabel = uilabel(app.BetweenneighboursparticlesTab);
            app.Bridge_NewphaseparameterLabel.Text = 'New phase parameters';
            app.VolumefractiondenseLabel = uilabel(app.BetweenneighboursparticlesTab);
            app.VolumefractiondenseLabel.Text = 'Volume fraction (dense)';
            app.Bridge_volumefraction_dense = uieditfield(app.BetweenneighboursparticlesTab, 'numeric');
            app.Bridge_volumefraction_dense.Limits = [0 1];
            app.Bridge_volumefraction_dense.ValueChangedFcn = createCallbackFcn(app, @Bridge_volumefraction_denseValueChanged, true);
            app.Bridge_volumefraction_dense.Value = 0.1;
            app.NanoporosityLabel = uilabel(app.BetweenneighboursparticlesTab);
            app.NanoporosityLabel.Text = 'Nanoporosity';
            app.Bridge_nanoporosity = uieditfield(app.BetweenneighboursparticlesTab, 'numeric');
            app.Bridge_nanoporosity.Limits = [0 1];
            app.Bridge_nanoporosity.ValueChangedFcn = createCallbackFcn(app, @Bridge_volumefraction_denseValueChanged, true);
            app.TargetvolumefractionLabel = uilabel(app.BetweenneighboursparticlesTab);
            app.TargetvolumefractionLabel.Text = 'Target volume fraction';
            app.Bridge_targetvolumefraction = uieditfield(app.BetweenneighboursparticlesTab, 'numeric');
            app.Bridge_targetvolumefraction.Limits = [0 1];
            app.Bridge_targetvolumefraction.Editable = 'off';
            app.Bridge_targetvolumefraction.Value = 0.1;
            app.Bridge_parameters_UITable = uitable(app.BetweenneighboursparticlesTab);
            app.Bridge_parameters_UITable.ColumnName = {'Parameter'; 'Range'; 'Value'};
            app.Bridge_parameters_UITable.RowName = {};
            app.Bridge_parameters_UITable.ColumnEditable = [false false true];
            app.Bridge_parameters_UITable.CellEditCallback = createCallbackFcn(app, @Bridge_parameters_UITableCellEdit, true);
            app.Bridge_generation = uibutton(app.BetweenneighboursparticlesTab, 'push');
            app.Bridge_generation.ButtonPushedFcn = createCallbackFcn(app, @Bridge_generationButtonPushed, true);
            app.Bridge_generation.Enable = 'off';
            app.Bridge_generation.Text = 'Generate';
            app.ProgressionGaugeLabel = uilabel(app.BetweenneighboursparticlesTab);
            app.ProgressionGaugeLabel.Text = 'Progression';
            app.ProgressionGauge = uigauge(app.BetweenneighboursparticlesTab, 'linear');
            app.ProgressionGauge.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.TextArea = uitextarea(app.BetweenneighboursparticlesTab);
            app.TextArea.Value = {'Not running'};
            app.Bridge_save = uibutton(app.BetweenneighboursparticlesTab, 'push');
            app.Bridge_save.ButtonPushedFcn = createCallbackFcn(app, @Bridge_saveButtonPushed, true);
            app.Bridge_save.Enable = 'off';
            app.Bridge_save.Text = 'Save';
            app.AchievedEditFieldLabel = uilabel(app.BetweenneighboursparticlesTab);
            app.AchievedEditFieldLabel.Text = 'Achieved';
            app.Bridged_AchievedEditField = uieditfield(app.BetweenneighboursparticlesTab, 'numeric');
            app.Bridged_AchievedEditField.Editable = 'off';
            % Show the figure after all components are created
            app.Microstructure_additive_phase_generation.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Microstructure_generation_additives

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Microstructure_additive_phase_generation)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Microstructure_additive_phase_generation)
        end
    end
end